classdef CascadingFailureGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        demandgenEditFieldLabel         matlab.ui.control.Label
        demandgenEditField              matlab.ui.control.NumericEditField
        loadshedEditFieldLabel          matlab.ui.control.Label
        loadshedEditField               matlab.ui.control.NumericEditField
        InitialFaliluresEditFieldLabel  matlab.ui.control.Label
        InitialFaliluresEditField       matlab.ui.control.NumericEditField
        capesterrEditFieldLabel         matlab.ui.control.Label
        capesterrEditField              matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        TopologyDropDown                matlab.ui.control.DropDown
        UIAxes3                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        InitialFailures;
        LoadGenerationRatio;
        LoadShedConstant;
        EstimationError;
        MaxFailedCapacity;
        Iterations;
        CaseName;
        States;
        NumberOfLines;
        MatrixName;
        SaveMatrixName;
        BarResolution; % Description
    end
    
    methods (Access = private)
        
        function updateplot(app)
            %             % Get Table UI component data
            %             t = app.UITable.DisplayData;
            %
            %             % Plot modified data
            %             x2 = t.Age;
            %             y2 = t.BloodPressure(:,2);
            %             plot(app.UIAxes2,x2,y2,'-o');
            %Get variables
            states = app.States;
            numberOfLines = app.NumberOfLines;
            
            %Assign values
            x1 = app.InitialFailures:numberOfLines;
            cS = cascadeStop(states, numberOfLines);
            y1 = cS(app.InitialFailures:numberOfLines);
            
            x2 = app.InitialFailures:numberOfLines;
            blackOutSize = BlackOutSize(states, app.CaseName);
            y2 = blackOutSize(app.InitialFailures:numberOfLines);
            
            resolution = app.BarResolution;
            %x3 = 0:resolution:app.NumberOfLines;
            y3 = failure_distribution(app.States, app.NumberOfLines); % resolution currently set to 1
            
            %Update Graphs
            %Cascade-Stop
            plot(app.UIAxes1, x1, y1);
            %Blackout Size
            plot(app.UIAxes2, x2, y2);
            %Custom -- Histogram of failure distribution
            %plot(app.UIAxes3, x3, y3);
            %histogram(app.UIAxes3, y3, resolution);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.Iterations = value;
        end

        % Value changed function: demandgenEditField
        function demandgenEditFieldValueChanged(app, event)
            value = app.demandgenEditField.Value;
            app.LoadGenerationRatio = value;
        end

        % Value changed function: loadshedEditField
        function loadshedEditFieldValueChanged(app, event)
            value = app.loadshedEditField.Value;
            app.LoadShedConstant = value;
        end

        % Value changed function: InitialFaliluresEditField
        function InitialFaliluresEditFieldValueChanged(app, event)
            value = app.InitialFaliluresEditField.Value;
            app.InitialFailures = value;
        end

        % Value changed function: capesterrEditField
        function capesterrEditFieldValueChanged(app, event)
            value = app.capesterrEditField.Value;
            app.EstimationError = value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %if loading in State
            %if using values input into GUI
            app.States = S_FindingStateSpace_ANN_dataset_function(app.CaseName, app.Iterations, app.InitialFailures, app.LoadGenerationRatio, app.LoadShedConstant, app.EstimationError);
            updateplot(app);
        end

        % Value changed function: TopologyDropDown
        function TopologyDropDownValueChanged(app, event)
            value = app.TopologyDropDown.Value;
            app.CaseName = value;
            %Change NumberOfLines according to CaseName
            if strcmp(app.CaseName, 'case118')
                app.NumberOfLines = 186;
                fprintf('Using IEEE 118 case, the number of lines is %f \n',app.NumberOfLines);
            elseif strcmp(app.CaseName, 'case300')
                app.NumberOfLines = 411;
                fprintf('Using IEEE 300 case, the number of lines is %f \n',app.NumberOfLines);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IterationsEditFieldLabel = uilabel(app.LeftPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 1;
            app.demandgenEditFieldLabel = uilabel(app.LeftPanel);
            app.demandgenEditFieldLabel.Text = 'demand/gen';
            app.demandgenEditField = uieditfield(app.LeftPanel, 'numeric');
            app.demandgenEditField.Limits = [0 1];
            app.demandgenEditField.ValueChangedFcn = createCallbackFcn(app, @demandgenEditFieldValueChanged, true);
            app.loadshedEditFieldLabel = uilabel(app.LeftPanel);
            app.loadshedEditFieldLabel.Text = 'load-shed';
            app.loadshedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.loadshedEditField.Limits = [0 1];
            app.loadshedEditField.ValueChangedFcn = createCallbackFcn(app, @loadshedEditFieldValueChanged, true);
            app.InitialFaliluresEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialFaliluresEditFieldLabel.Text = 'Initial Falilures';
            app.InitialFaliluresEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialFaliluresEditField.Limits = [0 Inf];
            app.InitialFaliluresEditField.RoundFractionalValues = 'on';
            app.InitialFaliluresEditField.ValueChangedFcn = createCallbackFcn(app, @InitialFaliluresEditFieldValueChanged, true);
            app.capesterrEditFieldLabel = uilabel(app.LeftPanel);
            app.capesterrEditFieldLabel.Text = 'cap est. err.';
            app.capesterrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.capesterrEditField.Limits = [0 1];
            app.capesterrEditField.ValueChangedFcn = createCallbackFcn(app, @capesterrEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TopologyDropDown = uidropdown(app.LeftPanel);
            app.TopologyDropDown.Items = {'Topology', 'case118', 'case300'};
            app.TopologyDropDown.ValueChangedFcn = createCallbackFcn(app, @TopologyDropDownValueChanged, true);
            app.TopologyDropDown.Value = 'Topology';
            app.UIAxes3 = uiaxes(app.LeftPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, 'Cascade Stop Probability')
            xlabel(app.UIAxes1, '# Failed Lines')
            ylabel(app.UIAxes1, 'Probability')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Blackout Size')
            xlabel(app.UIAxes2, '# Failed Transmission Lines')
            ylabel(app.UIAxes2, 'Blackout Size')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CascadingFailureGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end