classdef simPreferred < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Graphs_Tab_Group           matlab.ui.container.TabGroup
        Cascade_Stop_Tab           matlab.ui.container.Tab
        Cascade_Stop_Graph         matlab.ui.control.UIAxes
        Line_Failure_Tab           matlab.ui.container.Tab
        Line_Failure_Graph         matlab.ui.control.UIAxes
        Custom_Graph_Tab           matlab.ui.container.Tab
        Custom_Graph               matlab.ui.control.UIAxes
        TabGroup                   matlab.ui.container.TabGroup
        MATPOWERTab                matlab.ui.container.Tab
        InitialFailure_dropdown    matlab.ui.control.DropDown
        r_dropdown                 matlab.ui.control.DropDown
        theta_dropdown             matlab.ui.control.DropDown
        e_dropdown                 matlab.ui.control.DropDown
        CaseNameLabel              matlab.ui.control.Label
        CaseNameEditField          matlab.ui.control.EditField
        IterationsEditFieldLabel   matlab.ui.control.Label
        IterationsEditField        matlab.ui.control.NumericEditField
        SimulateButton             matlab.ui.control.Button
        RunningLamp                matlab.ui.control.Lamp
        AnalyticalTab              matlab.ui.container.Tab
        InitialFailure_dropdown_3  matlab.ui.control.DropDown
        r_dropdown_2               matlab.ui.control.DropDown
        theta_dropdown_2           matlab.ui.control.DropDown
        e_dropdown_2               matlab.ui.control.DropDown
        SimulateButton_2           matlab.ui.control.Button
        HumanErrorTab              matlab.ui.container.Tab
        InitialFailure_dropdown_4  matlab.ui.control.DropDown
        r_dropdown_3               matlab.ui.control.DropDown
        theta_dropdown_3           matlab.ui.control.DropDown
        e_dropdown_3               matlab.ui.control.DropDown
        human_error_DropDown       matlab.ui.control.DropDown
        SimulateButton_3           matlab.ui.control.Button
        CommunicationsTab          matlab.ui.container.Tab
        InitialFailure_dropdown_5  matlab.ui.control.DropDown
        r_dropdown_4               matlab.ui.control.DropDown
        theta_dropdown_4           matlab.ui.control.DropDown
        e_dropdown_4               matlab.ui.control.DropDown
        k_dropdown                 matlab.ui.control.DropDown
        SimulateButton_4           matlab.ui.control.Button
        CustomTab                  matlab.ui.container.Tab
        SimulateButton_5           matlab.ui.control.Button
        MATPOWERCheckBox           matlab.ui.control.CheckBox
        AnalyticalCheckBox         matlab.ui.control.CheckBox
        HumanErrorCheckBox         matlab.ui.control.CheckBox
        CommunicationsCheckBox     matlab.ui.control.CheckBox
        CustomCheckBox             matlab.ui.control.CheckBox
        SimulateMultipleButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        InitialFailures;
        LoadGenerationRatio;
        LoadShedConstant;
        EstimationError;
        MaxFailedCapacity;
        Version;
        Iterations;
        CaseName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InitialFailure_dropdown
        function InitialFailure_dropdownValueChanged(app, event)
            value = str2num(app.InitialFailure_dropdown.Value);
            app.InitialFailures=value;
        end

        % Value changed function: r_dropdown
        function r_dropdownValueChanged(app, event)
            value = str2num(app.r_dropdown.Value);
            app.LoadGenerationRatio=value;
        end

        % Value changed function: theta_dropdown
        function theta_dropdownValueChanged(app, event)
            value = str2num(app.theta_dropdown.Value);
            app.LoadShedConstant=value;
        end

        % Value changed function: e_dropdown
        function e_dropdownValueChanged(app, event)
            value = str2num(app.e_dropdown.Value);
            app.EstimationError=value;
        end

        % Callback function
        function C_max_dropdownValueChanged(app, event)
            value = str2num(app.C_max_dropdown.Value);
            app.MaxFailedCapacity=value;
        end

        % Callback function
        function Version_dropdownValueChanged(app, event)
            value = app.Version_dropdown.Value;
            app.Version = value;
            %choose simulator version from multiple available versions
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.Iterations = value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.RunningLamp.Color = [1 0 0];
            clc;
            %Run the function
            %NumberOfLines = 411;
            States = FindingStateSpace(app.CaseName, app.Iterations, app.InitialFailures, app.LoadGenerationRatio, app.LoadShedConstant, app.EstimationError, app.MaxFailedCapacity);
            NumberOfLines = 186;
            %blackout size
            %Blackoutsize=zeros(NumBranches,1);
            %for i=1:length(States(:,1))
            %    if(States(i,8)==-1)
            %        Blackoutsize(States(i,1))=Blackoutsize(States(i,1))+1;
            %    end
            %end
            %plot(app.Custom_Graph ,States(:,15), States(:,1));
            
            total_states = zeros(1, NumberOfLines);
            stable_states = zeros(1, NumberOfLines);
            % counting the occurances of the states
            for i = 1:length(States)
                if States(i,1) > 0
                    total_states(States(i,1)) = total_states(States(i,1)) + 1;
                end
                if States(i,8) == -1 % check if is the last state or not
                    stable_states(States(i,1)) = stable_states(States(i,1)) + 1;
                end
            end
            % finding the p_stop
            cascade_stop = zeros(1, NumberOfLines);
            for i = 1:NumberOfLines
                if total_states(i) ~= 0
                    cascade_stop(i) = stable_states(i)/total_states(i);
                end
            end
            %stem(3:NumberOfLines,cascade_stop(3:NumberOfLines))
            %xlabel('Number of failed lines')
            %ylabel('Probability of cascade-stop')
            %set(gca,'fontname','times', 'fontsize', 24)  % Set font to times 24
            
            %figure (2)
            plot(app.Cascade_Stop_Graph, 3:NumberOfLines, cascade_stop(3:NumberOfLines))
            xlabel('Number of failed transmission lines', 'FontSize', 24)
            ylabel('Cascade-stop probability', 'FontSize', 24)
            %Plot # of failed transmission lines vs time
            %figure(3)
            %plot(app.Line_Failure_Graph, States(:,15), States(:,1));
            %xlabel('Time (seconds)', 'FontSize', 24)
            %ylabel('Number of failed transmission lines', 'FontSize', 24)
            %Plot hop distance vs time
            %figure(4)
            %plot(app.Custom_Graph ,States(:,15), States(:,1));
            %xlabel('Time (seconds)', 'FontSize', 24)
            %ylabel('Average Hop Distance', 'FontSize', 24);
            
            app.RunningLamp.Color = [0 1 0];
        end

        % Value changed function: CaseNameEditField
        function CaseNameEditFieldValueChanged(app, event)
            value = app.CaseNameEditField.Value;
            app.CaseName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Graphs_Tab_Group = uitabgroup(app.UIFigure);
            app.Cascade_Stop_Tab = uitab(app.Graphs_Tab_Group);
            app.Cascade_Stop_Tab.Title = 'CSP';
            app.Cascade_Stop_Graph = uiaxes(app.Cascade_Stop_Tab);
            title(app.Cascade_Stop_Graph, 'Cascade-Stop Probability')
            xlabel(app.Cascade_Stop_Graph, '# of failed lines')
            ylabel(app.Cascade_Stop_Graph, 'Cascade Stop Probability')
            app.Line_Failure_Tab = uitab(app.Graphs_Tab_Group);
            app.Line_Failure_Tab.Title = 'LF';
            app.Line_Failure_Graph = uiaxes(app.Line_Failure_Tab);
            title(app.Line_Failure_Graph, 'Number of Line Failures')
            xlabel(app.Line_Failure_Graph, 'Time (minutes)')
            ylabel(app.Line_Failure_Graph, '# of Line Failures')
            app.Custom_Graph_Tab = uitab(app.Graphs_Tab_Group);
            app.Custom_Graph_Tab.Title = 'Custom';
            app.Custom_Graph = uiaxes(app.Custom_Graph_Tab);
            title(app.Custom_Graph, '')
            xlabel(app.Custom_Graph, 'X')
            ylabel(app.Custom_Graph, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MATPOWERTab = uitab(app.TabGroup);
            app.MATPOWERTab.Title = 'MATPOWER';
            app.InitialFailure_dropdown = uidropdown(app.MATPOWERTab);
            app.InitialFailure_dropdown.Items = {'Initial Failures', '2', '3', '4', '5'};
            app.InitialFailure_dropdown.ItemsData = {'1', '2', '3', '4', '5'};
            app.InitialFailure_dropdown.ValueChangedFcn = createCallbackFcn(app, @InitialFailure_dropdownValueChanged, true);
            app.InitialFailure_dropdown.Value = '1';
            app.r_dropdown = uidropdown(app.MATPOWERTab);
            app.r_dropdown.Items = {'r', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '', ''};
            app.r_dropdown.ItemsData = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.r_dropdown.ValueChangedFcn = createCallbackFcn(app, @r_dropdownValueChanged, true);
            app.r_dropdown.Value = '0';
            app.theta_dropdown = uidropdown(app.MATPOWERTab);
            app.theta_dropdown.Items = {'\theta', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown.ItemsData = {'0', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown.ValueChangedFcn = createCallbackFcn(app, @theta_dropdownValueChanged, true);
            app.theta_dropdown.Value = '0';
            app.e_dropdown = uidropdown(app.MATPOWERTab);
            app.e_dropdown.Items = {'e', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown.ItemsData = {'0', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown.ValueChangedFcn = createCallbackFcn(app, @e_dropdownValueChanged, true);
            app.e_dropdown.Value = '0';
            app.CaseNameLabel = uilabel(app.MATPOWERTab);
            app.CaseNameLabel.Text = 'Case Name';
            app.CaseNameEditField = uieditfield(app.MATPOWERTab, 'text');
            app.CaseNameEditField.ValueChangedFcn = createCallbackFcn(app, @CaseNameEditFieldValueChanged, true);
            app.IterationsEditFieldLabel = uilabel(app.MATPOWERTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.MATPOWERTab, 'numeric');
            app.IterationsEditField.Limits = [0 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 1;
            app.SimulateButton = uibutton(app.MATPOWERTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.RunningLamp = uilamp(app.MATPOWERTab);
            app.AnalyticalTab = uitab(app.TabGroup);
            app.AnalyticalTab.Title = 'Analytical';
            app.InitialFailure_dropdown_3 = uidropdown(app.AnalyticalTab);
            app.InitialFailure_dropdown_3.Items = {'Initial Failures', '2', '3', '4', '5'};
            app.InitialFailure_dropdown_3.ItemsData = {'1', '2', '3', '4', '5'};
            app.InitialFailure_dropdown_3.Value = '1';
            app.r_dropdown_2 = uidropdown(app.AnalyticalTab);
            app.r_dropdown_2.Items = {'r', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '', ''};
            app.r_dropdown_2.ItemsData = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.r_dropdown_2.Value = '0';
            app.theta_dropdown_2 = uidropdown(app.AnalyticalTab);
            app.theta_dropdown_2.Items = {'\theta', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown_2.ItemsData = {'0', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown_2.Value = '0';
            app.e_dropdown_2 = uidropdown(app.AnalyticalTab);
            app.e_dropdown_2.Items = {'e', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown_2.ItemsData = {'0', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown_2.Value = '0';
            app.SimulateButton_2 = uibutton(app.AnalyticalTab, 'push');
            app.SimulateButton_2.Text = 'Simulate';
            app.HumanErrorTab = uitab(app.TabGroup);
            app.HumanErrorTab.Title = 'Human Error';
            app.InitialFailure_dropdown_4 = uidropdown(app.HumanErrorTab);
            app.InitialFailure_dropdown_4.Items = {'Initial Failures', '2', '3', '4', '5'};
            app.InitialFailure_dropdown_4.ItemsData = {'1', '2', '3', '4', '5'};
            app.InitialFailure_dropdown_4.Value = '1';
            app.r_dropdown_3 = uidropdown(app.HumanErrorTab);
            app.r_dropdown_3.Items = {'r', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '', ''};
            app.r_dropdown_3.ItemsData = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.r_dropdown_3.Value = '0';
            app.theta_dropdown_3 = uidropdown(app.HumanErrorTab);
            app.theta_dropdown_3.Items = {'\theta', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown_3.ItemsData = {'0', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown_3.Value = '0';
            app.e_dropdown_3 = uidropdown(app.HumanErrorTab);
            app.e_dropdown_3.Items = {'e', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown_3.ItemsData = {'0', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown_3.Value = '0';
            app.human_error_DropDown = uidropdown(app.HumanErrorTab);
            app.human_error_DropDown.Items = {'HEP', 'Option 2', 'Option 3', 'Option 4'};
            app.human_error_DropDown.Value = 'HEP';
            app.SimulateButton_3 = uibutton(app.HumanErrorTab, 'push');
            app.SimulateButton_3.Text = 'Simulate';
            app.CommunicationsTab = uitab(app.TabGroup);
            app.CommunicationsTab.Title = 'Communications';
            app.InitialFailure_dropdown_5 = uidropdown(app.CommunicationsTab);
            app.InitialFailure_dropdown_5.Items = {'Initial Failures', '2', '3', '4', '5'};
            app.InitialFailure_dropdown_5.ItemsData = {'1', '2', '3', '4', '5'};
            app.InitialFailure_dropdown_5.Value = '1';
            app.r_dropdown_4 = uidropdown(app.CommunicationsTab);
            app.r_dropdown_4.Items = {'r', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '', ''};
            app.r_dropdown_4.ItemsData = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.r_dropdown_4.Value = '0';
            app.theta_dropdown_4 = uidropdown(app.CommunicationsTab);
            app.theta_dropdown_4.Items = {'\theta', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown_4.ItemsData = {'0', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown_4.Value = '0';
            app.e_dropdown_4 = uidropdown(app.CommunicationsTab);
            app.e_dropdown_4.Items = {'e', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown_4.ItemsData = {'0', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown_4.Value = '0';
            app.k_dropdown = uidropdown(app.CommunicationsTab);
            app.k_dropdown.Items = {'k', 'Option 2', 'Option 3', 'Option 4'};
            app.k_dropdown.Value = 'k';
            app.SimulateButton_4 = uibutton(app.CommunicationsTab, 'push');
            app.SimulateButton_4.Text = 'Simulate';
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.SimulateButton_5 = uibutton(app.CustomTab, 'push');
            app.SimulateButton_5.Text = 'Simulate';
            app.MATPOWERCheckBox = uicheckbox(app.UIFigure);
            app.MATPOWERCheckBox.Text = 'MATPOWER';
            app.AnalyticalCheckBox = uicheckbox(app.UIFigure);
            app.AnalyticalCheckBox.Text = 'Analytical';
            app.HumanErrorCheckBox = uicheckbox(app.UIFigure);
            app.HumanErrorCheckBox.Text = 'Human Error';
            app.CommunicationsCheckBox = uicheckbox(app.UIFigure);
            app.CommunicationsCheckBox.Text = 'Communications';
            app.CustomCheckBox = uicheckbox(app.UIFigure);
            app.CustomCheckBox.Text = 'Custom';
            app.SimulateMultipleButton = uibutton(app.UIFigure, 'push');
            app.SimulateMultipleButton.Text = 'Simulate Multiple';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simPreferred

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end