classdef PhysicalSimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhysicalSimulationGUI           matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        demandgenEditFieldLabel         matlab.ui.control.Label
        demandgenEditField              matlab.ui.control.NumericEditField
        loadshedEditFieldLabel          matlab.ui.control.Label
        loadshedEditField               matlab.ui.control.NumericEditField
        InitialFaliluresEditFieldLabel  matlab.ui.control.Label
        InitialFaliluresEditField       matlab.ui.control.NumericEditField
        capesterrEditFieldLabel         matlab.ui.control.Label
        capesterrEditField              matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        AnalyzeMatrixButton             matlab.ui.control.Button
        StateMatrixEditFieldLabel       matlab.ui.control.Label
        StateMatrixEditField            matlab.ui.control.EditField
        SaveMatrixAsEditFieldLabel      matlab.ui.control.Label
        SaveMatrixAsEditField           matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        TopologyDropDown                matlab.ui.control.DropDown
        HistogramResolutionEditFieldLabel  matlab.ui.control.Label
        HistogramResolutionEditField    matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        InitialFailures;
        LoadGenerationRatio;
        LoadShedConstant;
        EstimationError;
        MaxFailedCapacity;
        Iterations;
        CaseName;
        States;
        NumberOfLines;
        MatrixName;
        SaveMatrixName;
        BarResolution;
    end
    methods (Access = private)
        function updateplot(app)
            %             % Get Table UI component data
            %             t = app.UITable.DisplayData;
            %
            %             % Plot modified data
            %             x2 = t.Age;
            %             y2 = t.BloodPressure(:,2);
            %             plot(app.UIAxes2,x2,y2,'-o');
            %Get variables
            states = app.States;
            numberOfLines = app.NumberOfLines;
            
            %Assign values
            x1 = app.InitialFailures:numberOfLines;
            cS = cascadeStop(states, numberOfLines);
            y1 = cS(app.InitialFailures:numberOfLines);
            
            x2 = app.InitialFailures:numberOfLines;
            blackOutSize = BlackOutSize(states, app.CaseName);
            y2 = blackOutSize(app.InitialFailures:numberOfLines);
            
            resolution = app.BarResolution;
            %x3 = 0:resolution:app.NumberOfLines;
            y3 = failure_distribution(app.States, app.NumberOfLines); % resolution currently set to 1
            
            %Update Graphs
            %Cascade-Stop
            plot(app.UIAxes, x1, y1);
            %Blackout Size
            plot(app.UIAxes2, x2, y2);
            %Custom -- Histogram of failure distribution
            %plot(app.UIAxes3, x3, y3);
            %histogram(app.UIAxes3, y3, resolution);
            
        end
        function updateTable(app)
            states = app.States;
            app.UITable.Data = states;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
%             t = readtable('patients.xls');
%             vars = {'Age','Systolic','Diastolic','SelfAssessedHealthStatus','Smoker'};
%             
%             % Select a subset of the table array
%             t = t(1:20,vars);
%             
%             % Sort the data by age
%             t = sortrows(t,'Age');
%             
%             % Combine Systolic and Diastolic into one variable
%             t.BloodPressure = [t.Systolic t.Diastolic];
%             t.Systolic = [];
%             t.Diastolic = [];
%             
%             % Convert SelfAssessedHealthStatus to categorical
%             cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
%             t.SelfAssessedHealthStatus = cats;
%             
%             % Rearrange columns
%             t = t(:,[1 4 3 2]);
%             
%             % Add data to the Table UI Component
%             app.UITable.Data = t;
%             
%             % Plot the original data
%             x1 = app.UITable.Data.Age;
%             y1 = app.UITable.Data.BloodPressure(:,2);
%             plot(app.UIAxes,x1,y1,'o-');
%             
%             % Plot the data
%             updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            %updateplot(app);
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.Iterations = value;
        end

        % Callback function
        function TopologyEditFieldValueChanged(app, event)
            value = app.TopologyEditField.Value;
            app.CaseName = value;
            %Change NumberOfLines according to CaseName
            if strcmp(app.CaseName, 'case118')
                app.NumberOfLines = 186;
                fprintf('Using IEEE 118 case, the number of lines is %f \n',app.NumberOfLines);
            elseif strcmp(app.CaseName, 'case300')
                app.NumberOfLines = 411;
                fprintf('Using IEEE 300 case, the number of lines is %f \n',app.NumberOfLines);
            end
        end

        % Value changed function: demandgenEditField
        function demandgenEditFieldValueChanged(app, event)
            value = app.demandgenEditField.Value;
            app.LoadGenerationRatio = value;
        end

        % Value changed function: loadshedEditField
        function loadshedEditFieldValueChanged(app, event)
            value = app.loadshedEditField.Value;
            app.LoadShedConstant = value;
        end

        % Value changed function: InitialFaliluresEditField
        function InitialFaliluresEditFieldValueChanged(app, event)
            value = app.InitialFaliluresEditField.Value;
            app.InitialFailures = value;
        end

        % Value changed function: capesterrEditField
        function capesterrEditFieldValueChanged(app, event)
            value = app.capesterrEditField.Value;
            app.EstimationError = value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %if loading in State
            %if using values input into GUI
            app.States = S_FindingStateSpace_ANN_dataset_function(app.CaseName, app.Iterations, app.InitialFailures, app.LoadGenerationRatio, app.LoadShedConstant, app.EstimationError);
            updateTable(app);
            updateplot(app);
        end

        % Callback function
        function DemoButtonPushed(app, event)
            app.States = StatesDemoMatrix;
            updateTable(app);
            updateplot(app);
        end

        % Button pushed function: AnalyzeMatrixButton
        function AnalyzeMatrixButtonPushed2(app, event)
            app.InitialFailures = 3;
            %app.NumberOfLines = 186;
            app.States = readmatrix(app.MatrixName); %SimDemo.csv
            updateTable(app);
            updateplot(app);
        end

        % Value changed function: StateMatrixEditField
        function StateMatrixEditFieldValueChanged(app, event)
            value = app.StateMatrixEditField.Value;
            app.MatrixName = value;
        end

        % Value changed function: SaveMatrixAsEditField
        function SaveMatrixAsEditFieldValueChanged(app, event)
            value = app.SaveMatrixAsEditField.Value;
            app.SaveMatrixName = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            writematrix(app.States, app.SaveMatrixName);
        end

        % Value changed function: TopologyDropDown
        function TopologyDropDownValueChanged(app, event)
            value = app.TopologyDropDown.Value;
            app.CaseName = value;
            %value
             if strcmp(app.CaseName, 'case118')
                app.NumberOfLines = 186;
                fprintf('Using IEEE 118 case, the number of lines is %f \n',app.NumberOfLines);
            elseif strcmp(app.CaseName, 'case300')
                app.NumberOfLines = 411;
                fprintf('Using IEEE 300 case, the number of lines is %f \n',app.NumberOfLines);
            end
        end

        % Value changed function: HistogramResolutionEditField
        function HistogramResolutionEditFieldValueChanged(app, event)
            value = app.HistogramResolutionEditField.Value;
            app.BarResolution = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhysicalSimulationGUI = uifigure('Visible', 'off');
            app.PhysicalSimulationGUI.Name = 'MATPOWER Physical Simulation GUI';
            app.UIAxes = uiaxes(app.PhysicalSimulationGUI);
            title(app.UIAxes, 'Cascade Stop Probability')
            xlabel(app.UIAxes, '# Failed Lines')
            ylabel(app.UIAxes, 'Probability')
            app.UITable = uitable(app.PhysicalSimulationGUI);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UIAxes2 = uiaxes(app.PhysicalSimulationGUI);
            title(app.UIAxes2, 'Blackout Size')
            xlabel(app.UIAxes2, '# Failed Transmission Lines')
            ylabel(app.UIAxes2, 'Blackout Size')
            app.UIAxes3 = uiaxes(app.PhysicalSimulationGUI);
            title(app.UIAxes3, 'Custom')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.IterationsEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.PhysicalSimulationGUI, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 1;
            app.demandgenEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.demandgenEditFieldLabel.Text = 'demand/gen';
            app.demandgenEditField = uieditfield(app.PhysicalSimulationGUI, 'numeric');
            app.demandgenEditField.Limits = [0 1];
            app.demandgenEditField.ValueChangedFcn = createCallbackFcn(app, @demandgenEditFieldValueChanged, true);
            app.loadshedEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.loadshedEditFieldLabel.Text = 'load-shed';
            app.loadshedEditField = uieditfield(app.PhysicalSimulationGUI, 'numeric');
            app.loadshedEditField.Limits = [0 1];
            app.loadshedEditField.ValueChangedFcn = createCallbackFcn(app, @loadshedEditFieldValueChanged, true);
            app.InitialFaliluresEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.InitialFaliluresEditFieldLabel.Text = 'Initial Falilures';
            app.InitialFaliluresEditField = uieditfield(app.PhysicalSimulationGUI, 'numeric');
            app.InitialFaliluresEditField.Limits = [0 Inf];
            app.InitialFaliluresEditField.RoundFractionalValues = 'on';
            app.InitialFaliluresEditField.ValueChangedFcn = createCallbackFcn(app, @InitialFaliluresEditFieldValueChanged, true);
            app.capesterrEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.capesterrEditFieldLabel.Text = 'cap est. err.';
            app.capesterrEditField = uieditfield(app.PhysicalSimulationGUI, 'numeric');
            app.capesterrEditField.Limits = [0 1];
            app.capesterrEditField.ValueChangedFcn = createCallbackFcn(app, @capesterrEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.PhysicalSimulationGUI, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.AnalyzeMatrixButton = uibutton(app.PhysicalSimulationGUI, 'push');
            app.AnalyzeMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeMatrixButtonPushed2, true);
            app.AnalyzeMatrixButton.Text = 'Analyze Matrix';
            app.StateMatrixEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.StateMatrixEditFieldLabel.Text = 'State Matrix';
            app.StateMatrixEditField = uieditfield(app.PhysicalSimulationGUI, 'text');
            app.StateMatrixEditField.ValueChangedFcn = createCallbackFcn(app, @StateMatrixEditFieldValueChanged, true);
            app.SaveMatrixAsEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.SaveMatrixAsEditFieldLabel.Text = 'Save Matrix As';
            app.SaveMatrixAsEditField = uieditfield(app.PhysicalSimulationGUI, 'text');
            app.SaveMatrixAsEditField.ValueChangedFcn = createCallbackFcn(app, @SaveMatrixAsEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.PhysicalSimulationGUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TopologyDropDown = uidropdown(app.PhysicalSimulationGUI);
            app.TopologyDropDown.Items = {'Topology', 'case118', 'case300'};
            app.TopologyDropDown.ValueChangedFcn = createCallbackFcn(app, @TopologyDropDownValueChanged, true);
            app.TopologyDropDown.Value = 'Topology';
            app.HistogramResolutionEditFieldLabel = uilabel(app.PhysicalSimulationGUI);
            app.HistogramResolutionEditFieldLabel.Text = 'Histogram Resolution';
            app.HistogramResolutionEditField = uieditfield(app.PhysicalSimulationGUI, 'numeric');
            app.HistogramResolutionEditField.Limits = [1 100];
            app.HistogramResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @HistogramResolutionEditFieldValueChanged, true);
            app.HistogramResolutionEditField.Value = 1;
            % Show the figure after all components are created
            app.PhysicalSimulationGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhysicalSimGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhysicalSimulationGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhysicalSimulationGUI)
        end
    end
end