classdef simPreferred < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        r_dropdown                matlab.ui.control.DropDown
        InitialFailure_dropdown   matlab.ui.control.DropDown
        theta_dropdown            matlab.ui.control.DropDown
        e_dropdown                matlab.ui.control.DropDown
        C_max_dropdown            matlab.ui.control.DropDown
        Version_dropdown          matlab.ui.control.DropDown
        Graphs_Tab_Group          matlab.ui.container.TabGroup
        Cascade_Stop_Tab          matlab.ui.container.Tab
        Cascade_Stop_Graph        matlab.ui.control.UIAxes
        Line_Failure_Tab          matlab.ui.container.Tab
        Line_Failure_Graph        matlab.ui.control.UIAxes
        Custom_Graph_Tab          matlab.ui.container.Tab
        Custom_Graph              matlab.ui.control.UIAxes
        IterationsEditFieldLabel  matlab.ui.control.Label
        IterationsEditField       matlab.ui.control.NumericEditField
        SimulateButton            matlab.ui.control.Button
        CaseNameLabel             matlab.ui.control.Label
        CaseNameEditField         matlab.ui.control.EditField
        human_error_DropDown      matlab.ui.control.DropDown
        k_dropdown                matlab.ui.control.DropDown
        RunningLamp               matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        InitialFailures;
        LoadGenerationRatio;
        LoadShedConstant;
        EstimationError;
        MaxFailedCapacity;
        Version;
        Iterations;
        CaseName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InitialFailure_dropdown
        function InitialFailure_dropdownValueChanged(app, event)
            value = str2num(app.InitialFailure_dropdown.Value);
            app.InitialFailures=value;
        end

        % Value changed function: r_dropdown
        function r_dropdownValueChanged(app, event)
            value = str2num(app.r_dropdown.Value);
            app.LoadGenerationRatio=value;
        end

        % Value changed function: theta_dropdown
        function theta_dropdownValueChanged(app, event)
            value = str2num(app.theta_dropdown.Value);
            app.LoadShedConstant=value;
        end

        % Value changed function: e_dropdown
        function e_dropdownValueChanged(app, event)
            value = str2num(app.e_dropdown.Value);
            app.EstimationError=value;
        end

        % Value changed function: C_max_dropdown
        function C_max_dropdownValueChanged(app, event)
            value = str2num(app.C_max_dropdown.Value);
            app.MaxFailedCapacity=value;
        end

        % Value changed function: Version_dropdown
        function Version_dropdownValueChanged(app, event)
            value = app.Version_dropdown.Value;
            app.Version = value;
            %choose simulator version from multiple available versions
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.Iterations = value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.Version
            app.RunningLamp.Color = [1 0 0];
            %Run the function depending on the version dropdown
            if strcmp(app.CaseName, 'case118')
                NumberOfLines = 186;
                fprintf('Using IEEE 118 case, the number of lines is %f \n',NumberOfLines);
            elseif strcmp(app.CaseName, 'case300')
                NumberOfLines = 411;
                fprintf('Using IEEE 300 case, the number of lines is %f \n',NumberOfLines);
            end
            if strcmp(app.Version, 'Physical 2018')
                
                States = FindingStateSpacePhysical(app.CaseName, app.Iterations, app.InitialFailures, app.LoadGenerationRatio, app.LoadShedConstant, app.EstimationError);
                cascade_stop = cascadeStop(States, NumberOfLines);
                %set(gca,'fontname','times', 'fontsize', 24)  % Set font to times 24
                
                %figure (2)
                plot(app.Cascade_Stop_Graph, 3:NumberOfLines, cascade_stop(3:NumberOfLines))
                xlabel('Number of failed transmission lines', 'FontSize', 24)
                ylabel('Cascade-stop probability', 'FontSize', 24)
                %blackout size
                Blackoutsize = BlackOutSize(States);
%                 Blackoutsize=zeros(231,1);
%                 for i=1:length(States(:,1))
%                     if(States(i,8)==-1)
%                         Blackoutsize(States(i,1))=Blackoutsize(States(i,1))+1;
%                     end
%                 end
%                 bar(Blackoutsize);
%                 loglog(1:231,Blackoutsize);
                plot(app.Custom_Graph, 3:NumberOfLines, Blackoutsize(3:NumberOfLines))
                xlabel(app.Custom_Graph, 'Number of failed transmission lines', 'FontSize', 24)
                ylabel(app.Custom_Graph, 'Blackout Size', 'FontSize', 24)
                %Plot # of failed transmission lines vs time
                %figure(3)
                %             plot(app.Line_Failure_Graph, States(:,15), States(:,1));
                %             xlabel('Time (seconds)', 'FontSize', 24)
                %             ylabel('Number of failed transmission lines', 'FontSize', 24)
                %Plot hop distance vs time
                %figure(4)
                %             plot(app.Custom_Graph ,States(:,15), States(:,1));
                %             xlabel('Time (seconds)', 'FontSize', 24)
                %             ylabel('Average Hop Distance', 'FontSize', 24);
                
                app.RunningLamp.Color = [0 1 0];
            elseif strcmp(app.Version, 'ANN Dataset')
                States = FindingStateSpace_HumanErr(app.CaseName, app.Iterations, app.InitialFailures, app.LoadGenerationRatio, app.LoadShedConstant, app.EstimationError, app.MaxFailedCapacity);
                cascade_stop = cascadeStop(States, NumberOfLines);
                plot(app.Cascade_Stop_Graph, 3:NumberOfLines, cascade_stop(3:NumberOfLines))
                xlabel('Number of failed transmission lines', 'FontSize', 24)
                ylabel('Cascade-stop probability', 'FontSize', 24)
                %Plot # of failed transmission lines vs time
                %figure(3)
                plot(app.Line_Failure_Graph, States(:,15), States(:,1));
                xlabel('Time (seconds)', 'FontSize', 24)
                ylabel('Number of failed transmission lines', 'FontSize', 24)
                %Plot hop distance vs time
                %figure(4)
                plot(app.Custom_Graph ,States(:,15), States(:,1));
                xlabel('Time (seconds)', 'FontSize', 24)
                ylabel('Average Hop Distance', 'FontSize', 24);
                
                app.RunningLamp.Color = [0 1 0];
            end
        end

        % Value changed function: CaseNameEditField
        function CaseNameEditFieldValueChanged(app, event)
            value = app.CaseNameEditField.Value;
            app.CaseName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.r_dropdown = uidropdown(app.UIFigure);
            app.r_dropdown.Items = {'r', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '', ''};
            app.r_dropdown.ItemsData = {'0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.r_dropdown.ValueChangedFcn = createCallbackFcn(app, @r_dropdownValueChanged, true);
            app.r_dropdown.Value = '0';
            app.InitialFailure_dropdown = uidropdown(app.UIFigure);
            app.InitialFailure_dropdown.Items = {'Initial Failures', '2', '3', '4', '5'};
            app.InitialFailure_dropdown.ItemsData = {'1', '2', '3', '4', '5'};
            app.InitialFailure_dropdown.ValueChangedFcn = createCallbackFcn(app, @InitialFailure_dropdownValueChanged, true);
            app.InitialFailure_dropdown.Value = '1';
            app.theta_dropdown = uidropdown(app.UIFigure);
            app.theta_dropdown.Items = {'\theta', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown.ItemsData = {'0', '0', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.theta_dropdown.ValueChangedFcn = createCallbackFcn(app, @theta_dropdownValueChanged, true);
            app.theta_dropdown.Value = '0';
            app.e_dropdown = uidropdown(app.UIFigure);
            app.e_dropdown.Items = {'e', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown.ItemsData = {'0', '0.01', '0.02', '0.03', '0.04', '0.05', '0.06', '0.07', '0.08', '0.09', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.e_dropdown.ValueChangedFcn = createCallbackFcn(app, @e_dropdownValueChanged, true);
            app.e_dropdown.Value = '0';
            app.C_max_dropdown = uidropdown(app.UIFigure);
            app.C_max_dropdown.Items = {'C_max', '400', '600', '800', '1000', '1200', '1400', '1600', '1800'};
            app.C_max_dropdown.ItemsData = {'0', '400', '600', '800', '1000', '1200', '1400', '1600', '1800'};
            app.C_max_dropdown.ValueChangedFcn = createCallbackFcn(app, @C_max_dropdownValueChanged, true);
            app.C_max_dropdown.Value = '0';
            app.Version_dropdown = uidropdown(app.UIFigure);
            app.Version_dropdown.Items = {'Simulator Version', 'Physical 2018', 'ANN Dataset'};
            app.Version_dropdown.ValueChangedFcn = createCallbackFcn(app, @Version_dropdownValueChanged, true);
            app.Version_dropdown.Value = 'Physical 2018';
            app.Graphs_Tab_Group = uitabgroup(app.UIFigure);
            app.Cascade_Stop_Tab = uitab(app.Graphs_Tab_Group);
            app.Cascade_Stop_Tab.Title = 'CSP';
            app.Cascade_Stop_Graph = uiaxes(app.Cascade_Stop_Tab);
            title(app.Cascade_Stop_Graph, 'Cascade-Stop Probability')
            xlabel(app.Cascade_Stop_Graph, '# of failed lines')
            ylabel(app.Cascade_Stop_Graph, 'Cascade Stop Probability')
            app.Line_Failure_Tab = uitab(app.Graphs_Tab_Group);
            app.Line_Failure_Tab.Title = 'LF';
            app.Line_Failure_Graph = uiaxes(app.Line_Failure_Tab);
            title(app.Line_Failure_Graph, 'Number of Line Failures')
            xlabel(app.Line_Failure_Graph, 'Time (minutes)')
            ylabel(app.Line_Failure_Graph, '# of Line Failures')
            app.Custom_Graph_Tab = uitab(app.Graphs_Tab_Group);
            app.Custom_Graph_Tab.Title = 'Custom';
            app.Custom_Graph = uiaxes(app.Custom_Graph_Tab);
            title(app.Custom_Graph, '')
            xlabel(app.Custom_Graph, 'X')
            ylabel(app.Custom_Graph, 'Y')
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Limits = [0 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.CaseNameLabel = uilabel(app.UIFigure);
            app.CaseNameLabel.Text = 'Case Name';
            app.CaseNameEditField = uieditfield(app.UIFigure, 'text');
            app.CaseNameEditField.ValueChangedFcn = createCallbackFcn(app, @CaseNameEditFieldValueChanged, true);
            app.human_error_DropDown = uidropdown(app.UIFigure);
            app.human_error_DropDown.Items = {'Human Error', 'Option 2', 'Option 3', 'Option 4'};
            app.human_error_DropDown.Value = 'Human Error';
            app.k_dropdown = uidropdown(app.UIFigure);
            app.k_dropdown.Items = {'k', 'Option 2', 'Option 3', 'Option 4'};
            app.k_dropdown.Value = 'k';
            app.RunningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simPreferred

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end